classdef mainGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HthngcameragimstUIFigure    matlab.ui.Figure
        Panel                       matlab.ui.container.Panel
        TabGroup                    matlab.ui.container.TabGroup
        TrackingTab                 matlab.ui.container.Tab
        CameraPanel                 matlab.ui.container.Panel
        GridLayout                  matlab.ui.container.GridLayout
        UIAxes                      matlab.ui.control.UIAxes
        StartButton                 matlab.ui.control.Button
        PauseButton                 matlab.ui.control.Button
        StopButton                  matlab.ui.control.Button
        DatabaseTab                 matlab.ui.container.Tab
        UITable                     matlab.ui.control.Table
        CcthngtinchnhcangidngPanel  matlab.ui.container.Panel
        IDEditFieldLabel            matlab.ui.control.Label
        ID                          matlab.ui.control.EditField
        HvtnLabel                   matlab.ui.control.Label
        Name                        matlab.ui.control.EditField
        NgysinhDatePickerLabel      matlab.ui.control.Label
        DateOfBirth                 matlab.ui.control.DatePicker
        ImageContainer              matlab.ui.container.Panel
        Add                         matlab.ui.control.Button
        Update                      matlab.ui.control.Button
        Delete                      matlab.ui.control.Button
        Refresh                     matlab.ui.control.Button
        AddImage                    matlab.ui.control.Button
    end

    properties (Access = public)
        video % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % Kết nối vào thiết bị video sử dụng adapter winvideo
            vidObj = videoinput('winvideo', 1, "YUY2_640x480");
            app.video = vidObj

            % Khởi tạo một hình ảnh trắng cho việc vẽ hình vuông
            img = zeros(480, 640, 3, 'uint8');

            % Tạo một UI Axes trong giao diện người dùng để hiển thị hình ảnh và khuôn mặt
            axesHandle = app.UIAxes;
            axis(axesHandle, 'image');

            % Bắt đầu video
            start(vidObj);

            while ishandle(app.UIAxes)
                % Đọc hình ảnh từ video
                img = getsnapshot(vidObj);
    
                % Sử dụng thuật toán Haar-like để tìm khuôn mặt
                % (Bạn cần xác định các tham số phù hợp cho thuật toán này)
                %faceDetector = vision.CascadeObjectDetector();
                %bbox = step(faceDetector, img);
    
                % Vẽ hình vuông quanh khuôn mặt
                %for i = 1:size(bbox, 1)
                   % rectangle('Position', bbox(i, :), 'EdgeColor', [1, 0, 0], 'LineWidth', 2, 'Parent', axesHandle);
                %end
    
                % Hiển thị hình ảnh trên UI Axes
                imshow(img, 'Parent', axesHandle);
    
                drawnow;
            end

            % Dừng video và giải phóng tài nguyên
            stop(vidObj);
            delete(vidObj);
            clear vidObj;

        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            % Dừng video và giải phóng tài nguyên
            stop(app.video);
            delete(app.video);
            clear app.video;
        end

        % Button pushed function: Refresh
        function RefreshButtonPushed(app, event)
            addpath('functions\')
            newDisplayData = getUsers()
            app.UITable.Data = newDisplayData
            drawnow
            rmpath('functions\')
        end

        % Button pushed function: Add
        function AddButtonPushed(app, event)
            addpath('functions\')
            addUser(app.Name.Value, datestr(app.DateOfBirth.Value, 'yyyy-mm-dd'))
            % Hiển lại dữ liệu
            newDisplayData = getUsers()
            app.UITable.Data = newDisplayData
            drawnow
            % Reset form
            app.ID.Value = ''
            app.Name.Value = ''
            rmpath('functions\')
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices
            rowIndex = indices(:, 1)
            tableData = app.UITable.Data
            
            app.AddImage.Enable = true
            
            % Hiển thị dữ liệu lên form
            app.ID.Value = num2str(tableData{rowIndex, 1})
            app.Name.Value = tableData{rowIndex, 2}
            app.DateOfBirth.Value = datetime(tableData{rowIndex, 3}, 'InputFormat', 'yyyy-mm-dd')
        end

        % Button pushed function: Delete
        function DeleteButtonPushed(app, event)
            addpath('functions\')
            deleteUser(str2num(app.ID.Value))
            % Hiển lại dữ liệu
            newDisplayData = getUsers()
            app.UITable.Data = newDisplayData
            drawnow
            % Reset form
            app.ID.Value = ''
            app.Name.Value = ''
            rmpath('functions\')
        end

        % Button pushed function: AddImage
        function AddImageButtonPushed(app, event)
            [file,path] = uigetfile('', 'Chọn ảnh');
            if isequal(file,0)
                disp('User selected Cancel');
            else
                [~, ~, file_extension] = fileparts(fullfile(path,file))
                
                % Tạo tên ảnh
                newFileName = sprintf('%s%s', char(java.util.UUID.randomUUID().toString()), file_extension)
                
                % Lưu vào cơ sở dữ liệu
                addpath('functions\')
                addImageFor(str2num(app.ID.Value), sprintf('%s\\%s', num2str(app.ID.Value), newFileName))
                rmpath('functions\')
                
                
                % Đọc tệp ảnh ban đầu
                original_image = imread(fullfile(path,file))

                % Sử dụng hàm imwrite để ghi tệp ảnh vào vị trí mới
                % Kiểm tra xem thư mục con tồn tại
                if ~isfolder(sprintf('imgs\\%s\', num2str(app.ID.Value)))
                     % Nếu không tồn tại, tạo thư mục con
                     mkdir(sprintf('imgs\\%s\', app.ID.Value));
                end
                imwrite(original_image, sprintf('imgs\\%s\\%s', num2str(app.ID.Value), newFileName))
                
               
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HthngcameragimstUIFigure = uifigure('Visible', 'off');
            app.HthngcameragimstUIFigure.Name = 'Hệ thống camera giám sát';
            app.Panel = uipanel(app.HthngcameragimstUIFigure);
            app.TabGroup = uitabgroup(app.Panel);
            app.TrackingTab = uitab(app.TabGroup);
            app.TrackingTab.Title = 'Tracking';
            app.CameraPanel = uipanel(app.TrackingTab);
            app.CameraPanel.Title = 'Camera';
            app.GridLayout = uigridlayout(app.CameraPanel);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XLim = [0 1];
            app.UIAxes.YLim = [0 1];
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.StartButton = uibutton(app.TrackingTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.PauseButton = uibutton(app.TrackingTab, 'push');
            app.PauseButton.Text = 'Pause';
            app.StopButton = uibutton(app.TrackingTab, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.DatabaseTab = uitab(app.TabGroup);
            app.DatabaseTab.Title = 'Database';
            app.UITable = uitable(app.DatabaseTab);
            app.UITable.ColumnName = {'ID'; 'Họ và tên'; 'Ngày sinh'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.CcthngtinchnhcangidngPanel = uipanel(app.DatabaseTab);
            app.CcthngtinchnhcangidngPanel.Title = 'Các thông tin chính của người dùng';
            app.IDEditFieldLabel = uilabel(app.CcthngtinchnhcangidngPanel);
            app.IDEditFieldLabel.Text = 'ID';
            app.ID = uieditfield(app.CcthngtinchnhcangidngPanel, 'text');
            app.ID.Editable = 'off';
            app.HvtnLabel = uilabel(app.CcthngtinchnhcangidngPanel);
            app.HvtnLabel.Text = 'Họ và tên';
            app.Name = uieditfield(app.CcthngtinchnhcangidngPanel, 'text');
            app.NgysinhDatePickerLabel = uilabel(app.CcthngtinchnhcangidngPanel);
            app.NgysinhDatePickerLabel.Text = 'Ngày sinh';
            app.DateOfBirth = uidatepicker(app.CcthngtinchnhcangidngPanel);
            app.ImageContainer = uipanel(app.DatabaseTab);
            app.ImageContainer.Title = 'Hình ảnh nhận diện người dùng';
            app.Add = uibutton(app.DatabaseTab, 'push');
            app.Add.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.Add.Text = 'Thêm';
            app.Update = uibutton(app.DatabaseTab, 'push');
            app.Update.Text = 'Cập nhật';
            app.Delete = uibutton(app.DatabaseTab, 'push');
            app.Delete.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.Delete.Text = 'Xóa';
            app.Refresh = uibutton(app.DatabaseTab, 'push');
            app.Refresh.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.Refresh.Text = 'Làm mới';
            app.AddImage = uibutton(app.DatabaseTab, 'push');
            app.AddImage.ButtonPushedFcn = createCallbackFcn(app, @AddImageButtonPushed, true);
            app.AddImage.Enable = 'off';
            app.AddImage.Text = 'Thêm ảnh';
            % Show the figure after all components are created
            app.HthngcameragimstUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HthngcameragimstUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HthngcameragimstUIFigure)
        end
    end
end